classdef Microstrips < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        Panel                  matlab.ui.container.Panel          % Inputs
        LabelNumericEditField  matlab.ui.control.Label            % Imped. ...
        NumericEditField       matlab.ui.control.NumericEditField % [0 Inf]
        Label                  matlab.ui.control.Label            % Ω
        LabelNumericEditField3 matlab.ui.control.Label            % Permis....
        NumericEditField3      matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % Freq. d...
        NumericEditField4      matlab.ui.control.NumericEditField % [0 Inf]
        Label2                 matlab.ui.control.Label            % mm
        Label3                 matlab.ui.control.Label            % Hz
        Button                 matlab.ui.control.Button           % Calcular
        LabelDropDown          matlab.ui.control.Label            % Esp. Su...
        DropDown               matlab.ui.control.DropDown         % 0.0, 0....
        LabelNumericEditField5 matlab.ui.control.Label            % Tan. de...
        NumericEditField5      matlab.ui.control.NumericEditField % [-Inf Inf]
        Panel2                 matlab.ui.container.Panel          % Outputs
        Label5                 matlab.ui.control.Label            % mm
        Label6                 matlab.ui.control.Label            % m
        Label7                 matlab.ui.control.Label            % dB/cm
        LabelNumericEditField6 matlab.ui.control.Label            % Larg. d...
        NumericEditField6      matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField7 matlab.ui.control.Label            % At. no ...
        NumericEditField7      matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField8 matlab.ui.control.Label            % Comp. d...
        NumericEditField8      matlab.ui.control.NumericEditField % [0 Inf]
        Panel3                 matlab.ui.container.Panel          % Observação
        Label8                 matlab.ui.control.Label            % Os cálc...
        Button2                matlab.ui.control.Button          
        Button3                matlab.ui.control.Button           % Limpar
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function Calc(app)
            z0 = app.NumericEditField.Value;
            h =  str2double(app.DropDown.Value)/1000;
            Er =  app.NumericEditField3.Value;
            f =  app.NumericEditField4.Value;
            tanP =  app.NumericEditField5.Value;

            A = (z0/60)*sqrt((Er+1)/2) + ((Er-1)/(Er+1))*(0.23+(0.11)/(Er));
            B = (377*pi)/(2*z0*sqrt(Er));

            W1 = ((8*exp(A))*h)/(exp(2*A)-2);
            W2 = (2000*h/pi)*(B-1-log(2*B-1)+((Er-1)/(2*Er))*(log(B-1)+0.39-(0.61/Er)));

            if(((W1/h) <= 2) && ((W1/h) >= 0))
                app.NumericEditField6.Value = W1;
                W = W1;
            elseif((W2/h) > 2)
                 app.NumericEditField6.Value = W2;
                W = W2;
            end

            if(((W/h) >= 0) && ((W/h) < 0.6))
                Lambda = (300000000/(f*sqrt(Er)))*sqrt(Er/(1+0.6*(Er-1)*(W/h)^0.0297));
                app.NumericEditField8.Value = Lambda;
            elseif((W/h) >= 0.6)
                Lambda = (300000000/(f*sqrt(Er)))*sqrt(Er/(1+0.63*(Er-1)*(W/h)^0.1255));
                app.NumericEditField8.Value = Lambda;
            end

            Eeff = (Er+1)/2 + ((Er-1)/2)/sqrt(1+12*(h/W));

            alpha_d = 27.3*(Er/sqrt(Eeff))*((Eeff-1)/(Er-1))*(tanP*f/30000000);
            app.NumericEditField7.Value = alpha_d;
        end

        % Button3 button pushed function
        function clearBox(app)
            app.NumericEditField.Value = 0;
            app.DropDown.Value = '0.0';
            app.NumericEditField3.Value = 0;
            app.NumericEditField4.Value = 0;
            app.NumericEditField5.Value = 0;
            app.NumericEditField6.Value = 0;
            app.NumericEditField7.Value = 0;
            app.NumericEditField8.Value = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Inputs';
            app.Panel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Imped. Característica (Z0):';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.NumericEditField.Limits = [0 Inf];
            app.NumericEditField.ValueDisplayFormat = '%.3f';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Ω';
            app.LabelNumericEditField3 = uilabel(app.Panel);
            app.LabelNumericEditField3.Text = 'Permis. Relativa (Er):';
            app.NumericEditField3 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField3.Limits = [0 Inf];
            app.NumericEditField3.ValueDisplayFormat = '%.3f';
            app.LabelNumericEditField4 = uilabel(app.Panel);
            app.LabelNumericEditField4.Text = 'Freq. de Operação (f):';
            app.NumericEditField4 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField4.Limits = [0 Inf];
            app.NumericEditField4.ValueDisplayFormat = '%e';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'mm';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Hz';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Calc);
            app.Button.Text = 'Calcular';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Esp. Substrato (h):';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'0.0', '0.8', '1', '1.2', '1.6', '2.0', '2.4'};
            app.DropDown.Editable = 'on';
            app.DropDown.Value = '0.0';
            app.LabelNumericEditField5 = uilabel(app.Panel);
            app.LabelNumericEditField5.Text = 'Tan. de Perdas (tanδ):';
            app.NumericEditField5 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField5.ValueDisplayFormat = '%.3f';
            app.Button3 = uibutton(app.Panel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @clearBox);
            app.Button3.Text = 'Limpar';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Outputs';
            app.Panel2.Units = 'pixels';
            app.Label5 = uilabel(app.Panel2);
            app.Label5.Text = 'mm';
            app.Label6 = uilabel(app.Panel2);
            app.Label6.Text = 'm';
            app.Label7 = uilabel(app.Panel2);
            app.Label7.Text = 'dB/cm';
            app.LabelNumericEditField6 = uilabel(app.Panel2);
            app.LabelNumericEditField6.Text = 'Larg. da Trilha (W):';
            app.NumericEditField6 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField6.Limits = [0 Inf];
            app.NumericEditField6.ValueDisplayFormat = '%.3f';
            app.NumericEditField6.Editable = 'off';
            app.LabelNumericEditField7 = uilabel(app.Panel2);
            app.LabelNumericEditField7.Text = 'At. no Dielétrico (αd):';
            app.NumericEditField7 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField7.Limits = [0 Inf];
            app.NumericEditField7.ValueDisplayFormat = '%.3f';
            app.NumericEditField7.Editable = 'off';
            app.LabelNumericEditField8 = uilabel(app.Panel2);
            app.LabelNumericEditField8.Text = 'Comp. de Onda (λ):';
            app.NumericEditField8 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField8.Limits = [0 Inf];
            app.NumericEditField8.ValueDisplayFormat = '%e';
            app.NumericEditField8.Editable = 'off';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Observação';
            app.Panel3.Units = 'pixels';
            app.Label8 = uilabel(app.Panel3);
            app.Label8.Text = 'Os cálculos executados consideram a espessura do metal deprezível.';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Icon = 'microstrip.jpg';
            app.Button2.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Microstrips()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end