classdef SmithChart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        Panel1                  matlab.ui.container.Panel          % Parâme...
        LabelSlider             matlab.ui.control.Label            % R:
        SliderR                 matlab.ui.control.Slider           % [0 10]
        PanelR                  matlab.ui.container.Panel         
        ValueR                  matlab.ui.control.NumericEditField % [0 10]
        Label                   matlab.ui.control.Label            % 10^
        PotR                    matlab.ui.control.NumericEditField % [-Inf ...
        Label2                  matlab.ui.control.Label            % Ω
        Label3                  matlab.ui.control.Label            % L:
        SliderL                 matlab.ui.control.Slider           % [0 10]
        PanelL                  matlab.ui.container.Panel         
        ValueL                  matlab.ui.control.NumericEditField % [0 10]
        Label4                  matlab.ui.control.Label            % 10^
        PotL                    matlab.ui.control.NumericEditField % [-Inf ...
        Label5                  matlab.ui.control.Label            % H
        Label6                  matlab.ui.control.Label            % C:
        SliderC                 matlab.ui.control.Slider           % [0 10]
        PanelC                  matlab.ui.container.Panel         
        ValueC                  matlab.ui.control.NumericEditField % [0 10]
        Label7                  matlab.ui.control.Label            % 10^
        PotC                    matlab.ui.control.NumericEditField % [-Inf ...
        Label8                  matlab.ui.control.Label            % F
        Label9                  matlab.ui.control.Label            % G:
        SliderG                 matlab.ui.control.Slider           % [0 10]
        PanelG                  matlab.ui.container.Panel         
        ValueG                  matlab.ui.control.NumericEditField % [0 10]
        Label10                 matlab.ui.control.Label            % 10^
        PotG                    matlab.ui.control.NumericEditField % [-Inf ...
        Label11                 matlab.ui.control.Label            % S
        ButtonCalcZ0            matlab.ui.control.Button           % Calcul...
        ButtonChange2Z0         matlab.ui.control.Button           % Entrar...
        Label20                 matlab.ui.control.Label            % Freq. ...
        Freq1                   matlab.ui.control.NumericEditField % [0 Inf]
        Label21                 matlab.ui.control.Label            % Hz
        MainPanel               matlab.ui.container.Panel         
        PanelZin                matlab.ui.container.Panel          % Imped....
        LabelNumericEditField13 matlab.ui.control.Label            % Distân...
        ValueD                  matlab.ui.control.NumericEditField % [0 Inf]
        Label15                 matlab.ui.control.Label            % λ
        ButtonCalcZin           matlab.ui.control.Button           % Calcul...
        Label16                 matlab.ui.control.Label            % Zin:
        ValueRZin               matlab.ui.control.NumericEditField % [0 Inf]
        Label17                 matlab.ui.control.Label            % + j
        ValueIZin               matlab.ui.control.NumericEditField % [-Inf ...
        Label25                 matlab.ui.control.Label            % Ω
        Label40                 matlab.ui.control.Label            % Γin:
        ValueRGamaIn            matlab.ui.control.NumericEditField % [-1 1]
        Label41                 matlab.ui.control.Label            % + j
        ValueIGamaIn            matlab.ui.control.NumericEditField % [-1 1]
        Label42                 matlab.ui.control.Label            % Ω
        Panel10                 matlab.ui.container.Panel          % Casame...
        MatchingType            matlab.ui.control.DropDown         % Elemen...
        ButtonCalcMatching      matlab.ui.control.Button           % Realizar
        PanelZL                 matlab.ui.container.Panel          % Carga ...
        LabelNumericEditField9  matlab.ui.control.Label            % Z.L:
        ValueRZL                matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField10 matlab.ui.control.Label            % + j
        ValueIZL                matlab.ui.control.NumericEditField % [-Inf ...
        Label24                 matlab.ui.control.Label            % Ω
        ButtonPlotZL            matlab.ui.control.Button           % Plotar...
        Label37                 matlab.ui.control.Label            % ΓL:
        ValueRGamaL             matlab.ui.control.NumericEditField % [-1 1]
        Label38                 matlab.ui.control.Label            % + j
        ValueIGamaL             matlab.ui.control.NumericEditField % [-1 1]
        Label39                 matlab.ui.control.Label            % Ω
        Warning                 matlab.ui.container.Panel          % Aviso!!
        Label26                 matlab.ui.control.Label            % Imped....
        ValueRwZ0               matlab.ui.control.NumericEditField % [0 Inf]
        Label27                 matlab.ui.control.Label            % + j
        ValueIwZ0               matlab.ui.control.NumericEditField % [-Inf ...
        Label28                 matlab.ui.control.Label            % Ω
        Label29                 matlab.ui.control.Label            % Const....
        ValueRwGama             matlab.ui.control.NumericEditField % [0 Inf]
        Label30                 matlab.ui.control.Label            % + j
        ValueIwGama             matlab.ui.control.NumericEditField % [-Inf ...
        Label31                 matlab.ui.control.Label            % Ω
        Panel13                 matlab.ui.container.Panel          % Observ...
        TextArea2               matlab.ui.control.TextArea         % A Cart...
        ButtonWarningOK         matlab.ui.control.Button           % OK, Ci...
        Panel2                  matlab.ui.container.Panel          % Entrar...
        LabelNumericEditField11 matlab.ui.control.Label            % Imped....
        ValueInZ0               matlab.ui.control.NumericEditField % [-Inf ...
        Label13                 matlab.ui.control.Label            % Ω
        LabelNumericEditField12 matlab.ui.control.Label            % + j
        ValueInBeta             matlab.ui.control.NumericEditField % [-Inf ...
        Label12                 matlab.ui.control.Label            % Const....
        Label14                 matlab.ui.control.Label            % /m
        Panel8                  matlab.ui.container.Panel          % Observ...
        TextArea                matlab.ui.control.TextArea         % Lembre...
        ButtonPanel2OK          matlab.ui.control.Button           % OK
        Label43                 matlab.ui.control.Label            % Freq. ...
        Freq2                   matlab.ui.control.NumericEditField % [0 Inf]
        Label44                 matlab.ui.control.Label            % Hz
        Panel3                  matlab.ui.container.Panel          % Result...
        Label32                 matlab.ui.control.Label            % Imped....
        ValueOutZ0              matlab.ui.control.NumericEditField % [-Inf ...
        Label33                 matlab.ui.control.Label            % Ω
        Label34                 matlab.ui.control.Label            % + j
        ValueOutBeta            matlab.ui.control.NumericEditField % [-Inf ...
        Label35                 matlab.ui.control.Label            % Const....
        Label36                 matlab.ui.control.Label            % /m
        ButtonPanel3OK          matlab.ui.control.Button           % OK
        ButtonRestart           matlab.ui.control.Button           % Reiniciar
        PanelAxes               matlab.ui.container.Panel         
        UIAxes                  matlab.ui.control.UIAxes           % Smith ...
        ButtonClearAxes         matlab.ui.control.Button           % Limpar...
        PanelCSerie             matlab.ui.container.Panel          % C_Série
        Label45                 matlab.ui.control.Label            % Dist. ...
        DCSerie                 matlab.ui.control.NumericEditField % [0 Inf]
        Label46                 matlab.ui.control.Label            % λ
        Label47                 matlab.ui.control.Label            % Capaci...
        CSerie                  matlab.ui.control.NumericEditField % [0 Inf]
        Label48                 matlab.ui.control.Label            % F
        ButtonCSerie            matlab.ui.control.Button           % OK
        PanelCParalelo          matlab.ui.container.Panel          % C_Para...
        Label49                 matlab.ui.control.Label            % Dist. ...
        DCParalelo              matlab.ui.control.NumericEditField % [0 Inf]
        Label50                 matlab.ui.control.Label            % λ
        Label51                 matlab.ui.control.Label            % Capaci...
        CParalelo               matlab.ui.control.NumericEditField % [0 Inf]
        Label52                 matlab.ui.control.Label            % F
        ButtonCParalelo         matlab.ui.control.Button           % OK
        PanelLParalelo          matlab.ui.container.Panel          % L_Para...
        Label53                 matlab.ui.control.Label            % Dist. ...
        DLParalelo              matlab.ui.control.NumericEditField % [0 Inf]
        Label54                 matlab.ui.control.Label            % λ
        Label55                 matlab.ui.control.Label            % Indutor:
        LParalelo               matlab.ui.control.NumericEditField % [0 Inf]
        Label56                 matlab.ui.control.Label            % H
        ButtonLParalelo         matlab.ui.control.Button           % OK
        PanelLSerie             matlab.ui.container.Panel          % L_Série
        Label57                 matlab.ui.control.Label            % Dist. ...
        DLSerie                 matlab.ui.control.NumericEditField % [0 Inf]
        Label58                 matlab.ui.control.Label            % λ
        Label59                 matlab.ui.control.Label            % Indutor:
        LSerie                  matlab.ui.control.NumericEditField % [0 Inf]
        Label60                 matlab.ui.control.Label            % H
        ButtonLSerie            matlab.ui.control.Button           % OK
        PanelQuarto             matlab.ui.container.Panel          % Transf...
        Label61                 matlab.ui.control.Label            % Imp. d...
        ValueZm                 matlab.ui.control.NumericEditField % [0 Inf]
        Label62                 matlab.ui.control.Label            % Ω
        ButtonOKZm              matlab.ui.control.Button           % OK
        PanelTocoSimples        matlab.ui.container.Panel          % Toco S...
        ButtonGroup             matlab.ui.container.ButtonGroup    % Tipo
        ButtonSerie             matlab.ui.control.RadioButton      % Série
        ButtonParalelo          matlab.ui.control.RadioButton      % Paralelo
        ButtonGroup2            matlab.ui.container.ButtonGroup    % Fecham...
        ButtonCC                matlab.ui.control.RadioButton      % Curto ...
        ButtonCA                matlab.ui.control.RadioButton      % Circui...
        Label63                 matlab.ui.control.Label            % Distân...
        ValueDT                 matlab.ui.control.NumericEditField % [0 Inf]
        Label64                 matlab.ui.control.Label            % λ
        Label65                 matlab.ui.control.Label            % Tamanh...
        ValueT                  matlab.ui.control.NumericEditField % [0 Inf]
        Label66                 matlab.ui.control.Label            % λ
        ButtonCloseToco         matlab.ui.control.Button           % Fechar
        ButtonOKToco            matlab.ui.control.Button           % OK
    end


    methods (Access = private)
        
        function ClearFields(app)
            % Fields of Panel1
            app.SliderR.Value = 0;
            app.ValueR.Value = 0;
            app.PotR.Value = 0;
            app.SliderL.Value = 0;
            app.ValueL.Value = 0;
            app.PotL.Value = 0;
            app.SliderC.Value = 0;
            app.ValueC.Value = 0;
            app.PotC.Value = 0;
            app.SliderG.Value = 0;
            app.ValueG.Value = 0;
            app.PotG.Value = 0;
            app.Freq1.Value = 0;
            
            % Fields of Panel2
            app.ValueInZ0.Value = 0;
            app.ValueInBeta.Value = 0;
            app.Freq2.Value = 0;
            
            % Fields of MainPanel
            % PanelZL
            app.ValueRZL.Value = 0;
            app.ValueIZL.Value = 0;
            app.ValueRGamaL.Value = 0;
            app.ValueIGamaL.Value = 0;
            % PanelZin
            app.ValueD.Value = 0;
            app.ValueRZin.Value = 0;
            app.ValueIZin.Value = 0;
            app.ValueRGamaIn.Value = 0;
            app.ValueIGamaIn.Value = 0;
            % Panel10
            app.MatchingType.Value = '0';
            
            % PanelAxes
            cla(app.UIAxes);
            
            % PanelCSerie
            app.DCSerie.Value = 0;
            app.CSerie.Value = 0;
            
            % PanelCParalelo
            app.DCParalelo.Value = 0;
            app.CParalelo.Value = 0;
            
            % PanelLSerie
            app.DLSerie.Value = 0;
            app.LSerie.Value = 0;
            
            % PanelLParalelo
            app.DLParalelo.Value = 0;
            app.LParalelo.Value = 0;
            
            % PanelQuarto
            app.ValueZm.Value = 0;
            
            % PanelTocoSimples
            app.ValueDT.Value = 0;
            app.ValueT.Value = 0;
        end

        function result = Bissec(app, a, b, r, im, mode)
            ok = false;
            
            if mode == 1
                fa = sqrt((im/(1+r))^2 - (im*a - im*r/(1+r))^2) + sqrt(1 - (im*a - im)^2) - 1;
                fb = sqrt((im/(1+r))^2 - (im*b - im*r/(1+r))^2) + sqrt(1 - (im*b - im)^2) - 1;
				
                c = 1;
                while b == 1
                    b_linha = b - 0.1/c;
                    fb_linha = sqrt((im/(1+r))^2 - (im*b_linha - im*r/(1+r))^2) + sqrt(1 - (im*b_linha - im)^2) - 1;
                    if fb_linha < 0
                        a = b_linha;
                        fa = fb_linha;
                    else
                        b = b_linha;
                        fb = fb_linha;
                    end
                    c = c+1;
                end
				
		x1 = (b+a)/2;
                fx1 = sqrt((im/(1+r))^2 - (im*x1 - im*r/(1+r))^2) + sqrt(1 - (im*x1 - im)^2) - 1;
				
            elseif mode == 2
                fa = sqrt((im/(1+r))^2 - (im*a - im*r/(1+r))^2) - sqrt(1 - (im*a - im)^2) - 1;
                fb = sqrt((im/(1+r))^2 - (im*b - im*r/(1+r))^2) - sqrt(1 - (im*b - im)^2) - 1;
                
                d = 1;
                c = 1;
                while (abs(imag(fa)) > 0.0001)
                    a_linha = a + 0.1*c/d;
                    fa_linha = sqrt((im/(1+r))^2 - (im*a_linha - im*r/(1+r))^2) - sqrt(1 - (im*a_linha - im)^2) - 1;
                    if a_linha <= 1
                        if (abs(imag(fa_linha)) > 0.0001)
                            a = a_linha;
                            fa = fa_linha;
                            c = c+1;
                        else
                            if real(fa_linha) >= 0
                                a = a_linha;
                                fa = real(fa_linha);
                            else
                                b = a_linha;
                                fb = real(fa_linha);
                                d = d+1;
                            end
                        end
                    else
                        d = d+1;
                    end
                end   
               
                x1 = (b+a)/2;
                fx1 = sqrt((im/(1+r))^2 - (im*x1 - im*r/(1+r))^2) - sqrt(1 - (im*x1 - im)^2) - 1;
            end

            c = 1;
            while ((ok == false) && (c < 100000))                
                if abs(real(fa)) <= 0.0001
                    result = a;
                    ok = true;
                elseif abs(real(fb)) <= 0.0001
                    result = b;
                    ok = true;
                elseif abs(real(fx1)) <= 0.0001
                    result = x1;
                    ok = true;
                else
                    if real(fa)*real(fx1) < 0
                        b = x1;
                        fb = fx1;
                    elseif real(fb)*real(fx1) < 0
                        a = x1;
                        fa = fx1;
                    end
                end
                c = c+1;
                x1 = (b+a)/2;

                if mode == 1
                        fx1 = sqrt((im/(1+r))^2 - (im*x1 - im*r/(1+r))^2) + sqrt(1 - (im*x1 - im)^2) - 1;
                elseif mode == 2
                        fx1 = sqrt((im/(1+r))^2 - (im*x1 - im*r/(1+r))^2) - sqrt(1 - (im*x1 - im)^2) - 1;
                end
            end
        end

        function InitAxes(app)
            x = linspace(-1,1);
            y = sqrt(1-x.^(2));
            plot(app.UIAxes, x, y, 'k');
            hold(app.UIAxes, 'on');
            y = -sqrt(1-x.^(2));
            plot(app.UIAxes, x, y, 'k');
            y = x - x;
            plot(app.UIAxes, x, y, 'k');
            hold(app.UIAxes, 'off');
        end

        function Point(app, xc, yc)
            R = 0.03;
            x = linspace(xc-R,xc+R);
            y = sqrt((R)^2-(x-xc).^(2)) + yc;
            plot(app.UIAxes, x, y, 'r');
            y = -sqrt((R)^2-(x-xc).^(2)) + yc;
            plot(app.UIAxes, x, y, 'r');
        end

        function GamaConstCircle(app, gamaR, gamaI)
            R = sqrt(gamaR^2 + gamaI^2);
            x = linspace(-R,R);
            y = sqrt((R)^2-(x).^(2));
            plot(app.UIAxes, x, y, 'm');
            y = -sqrt((R)^2-(x).^(2));
            plot(app.UIAxes, x, y, 'm');
        end

        function teta = d2teta(app, d)
            teta = pi - 4*pi*d;
        end

        function d = teta2d(app, teta)
            d = 0.25 - teta/(4*pi);
        end

        function gama = PlotZ(app, zl)
            r = real(zl);
            deltaX1 = -1/(1+r) + (r/(1+r));
            deltaX2 = 1/(1+r) + (r/(1+r));
            x = linspace(deltaX1,deltaX2);
            y = sqrt((1/(1+r))^2-(x-(r/(1+r))).^(2));
            plot(app.UIAxes, x, y, 'g');
            y = -sqrt((1/(1+r))^2-(x-(r/(1+r))).^(2));
            plot(app.UIAxes, x, y, 'g');
            
            im = imag(zl);
            
            if (im > 0) && (im <= 1)
                a = -1;
                b = 0;
                deltaX1 = Bissec(app, a, b, 0, im, 1);
                x = linspace(deltaX1,1);
                y = -sqrt((1/im)^2-(x-1).^(2)) + 1/im;
                plot(app.UIAxes, x, y, 'b');
                
                a = -1;
                b = 1;
                gamaLR = Bissec(app, a, b, r, im, 1);
                gamaLI = sqrt((1/(1+r))^2-(gamaLR-(r/(1+r))).^(2));
            elseif (im < 0) && (im >= -1)
                a = -1;
                b = 0;
                deltaX1 = Bissec(app, a, b, 0, im, 1);
                x = linspace(deltaX1,1);
                y = sqrt((1/im)^2-(x-1).^(2)) + 1/im;
                plot(app.UIAxes, x, y, 'b');
                
                a = -1;
                b = 1;
                gamaLR = Bissec(app, a, b, r, im, 1);
                gamaLI = -sqrt((1/(1+r))^2-(gamaLR-(r/(1+r))).^(2));
            elseif (im > 1)
                a = 0;
                b = 1;
                x = linspace(1-1/im,1);
                y = -sqrt((1/im)^2-(x-1).^(2)) + 1/im;
                plot(app.UIAxes, x, y, 'b');
                deltaX2 = Bissec(app, a, b, 0, im, 2);
                x = linspace(1-1/im,deltaX2);
                y = sqrt((1/im)^2-(x-1).^(2)) + 1/im;
                plot(app.UIAxes, x, y, 'b');
                
                a = 0;
                b = 1;
                if abs(im) <= r+1
                    gamaLR = Bissec(app, a, b, r, im, 1);
                else
                    gamaLR = Bissec(app, a, b, r, im, 2);
                end
                gamaLI = sqrt((1/(1+r))^2-(gamaLR-(r/(1+r))).^(2));
            elseif (im < -1)
                a = 0;
                b = 1;
                x = linspace(1+1/im,1);
                y = sqrt((1/im)^2-(x-1).^(2)) + 1/im;
                plot(app.UIAxes, x, y, 'b');
                deltaX2 = Bissec(app, a, b, 0, im, 2);
                x = linspace(1+1/im,deltaX2);
                y = -sqrt((1/im)^2-(x-1).^(2)) + 1/im;
                plot(app.UIAxes, x, y, 'b');
                
                a = 0;
                b = 1;
                if abs(im) <= r+1
                    gamaLR = Bissec(app, a, b, r, im, 1);
                else
                    gamaLR = Bissec(app, a, b, r, im, 2);
                end
                gamaLI = -sqrt((1/(1+r))^2-(gamaLR-(r/(1+r))).^(2));
            elseif im == 0
                gamaLI = 0;
                gamaLR = (r-1)/(r+1);
            end
            
            Point(app, gamaLR, gamaLI);
            w = gamaLR + 0.05;
            h = gamaLI;
            str = strcat(num2str(r), ' + j*', num2str(im));
            text(app.UIAxes, w, h, str);
            gama = complex(gamaLR, gamaLI);
        end
        
        function gama = z2gama(app, z)
	    gama = (z-1)/(z+1);
        end

        function z = gama2z(app, gama)
	    z = (1+gama)/(1-gama);
        end

        function z = Go2R1(app, zin, mode)
	    gama = z2gama(app, zin);
            
            gama0R = (abs(gama))^2;
	
	    if mode == 1
		gama0I = sqrt(gama0R - gama0R^2);
	    elseif mode == 2
		gama0I = -sqrt(gama0R - gama0R^2);
	    end
	
	    gama0 = complex(gama0R, gama0I);
	    z = gama2z(app, gama0);
        end

        function teta = gama2teta(app, gama)
            if (real(gama) < 0)
                teta = pi + atan(imag(gama)/real(gama));
            elseif real(gama) > 0
                teta = atan(imag(gama)/real(gama));
            elseif real(gama) == 0
                teta = pi/2;
            end
        end

        function d = getD(app, Z1, Z2)
	    gama1 = z2gama(app, Z1);
	    teta1 = gama2teta(app, gama1);
	    d1 = teta2d(app, teta1);
           	
	    gama2 = z2gama(app, Z2);
	    teta2 = gama2teta(app, gama2);
	    d2 = teta2d(app, teta2);
	    
            if d2-d1 > 0
	        d = abs(d2-d1);
            else 
                d = 0.5-abs(d2-d1);
            end
        end
        
        function d = getDfrom0(app, Z)
            gama = z2gama(app, Z);
	    teta = gama2teta(app, gama);
	    d = teta2d(app, teta);
        end
        
        function d = getDfromInf(app, Z2)
	    d1 = 0.25;
	
	    gama2 = z2gama(app, Z2);
	    teta2 = gama2teta(app, gama2);
	    d2 = teta2d(app, teta2);
	    
            if d2-d1 > 0
	        d = abs(d2-d1);
            else 
                d = 0.5-abs(d2-d1);
            end
        end

        function C = getC_Serie(app, f)
	    Zl = complex(app.ValueRZL.Value, app.ValueIZL.Value);
	    zl = Zl/app.ValueOutZ0.Value;    
     
	    z = Go2R1(app, zl, 1);
            hold(app.UIAxes, 'on');
            gamaz = PlotZ(app, z);
            hold(app.UIAxes, 'off');
            pause(2);
     
	    Z = z*app.ValueOutZ0.Value;
	    Zc = imag(Z);
	    C = 1/(2*pi*f*Zc);
        end

        function C_Serie(app)
            f = app.Freq1.Value;
	    C = getC_Serie(app, f);
	    Zc = complex(0, -1/(2*pi*f*C));
	    zc = Zc/app.ValueOutZ0.Value;
            
            hold(app.UIAxes, 'on');
	    gamaC = PlotZ(app, zc);
	    hold(app.UIAxes, 'off');
	
	    Zl = complex(app.ValueRZL.Value, app.ValueIZL.Value);
	    zl = Zl/app.ValueOutZ0.Value;
            z = Go2R1(app, zl, 1);
	
	    d = getD(app, zl, z);
            app.DCSerie.Value = d;
            app.CSerie.Value = C;
        end
        
        function C = getC_Paralelo(app, f)
           	Zl = complex(app.ValueRZL.Value, app.ValueIZL.Value);
	        zl = Zl/app.ValueOutZ0.Value;
                yl = 1/zl;
                
                hold(app.UIAxes, 'on');
                gamaYl = PlotZ(app, yl);
                hold(app.UIAxes, 'off');
                pause(2);
           	
           	y = Go2R1(app, yl, 2);
                hold(app.UIAxes, 'on');
                gamay = PlotZ(app, y);
                hold(app.UIAxes, 'off');
                pause(2);
                
           	Y = y/app.ValueOutZ0.Value;
           	Yc = -imag(Y);
           	C = Yc/(2*pi*f);
        end

        function C_Paralelo(app)
                f = app.Freq1.Value;
           	C = getC_Paralelo(app, f);
           	Yc = complex(0, (2*pi*f*C));
           	yc = Yc*app.ValueOutZ0.Value;
            
                hold(app.UIAxes, 'on');
                gamaYc = PlotZ(app, yc);
                hold(app.UIAxes, 'off');
           	
           	Zl = complex(app.ValueRZL.Value, app.ValueIZL.Value);
           	zl = Zl/app.ValueOutZ0.Value;
           	yl = 1/zl;
           
           	y = Go2R1(app, yl, 2);
            
               	d = getD(app, yl, y);
                app.DCParalelo.Value = d;
                app.CParalelo.Value = C;
        end

        function L = getL_Serie(app, f)
            Zl = complex(app.ValueRZL.Value, app.ValueIZL.Value);
	    zl = Zl/app.ValueOutZ0.Value;    
     
	    z = Go2R1(app, zl, 2);
            hold(app.UIAxes, 'on');
            gamaz = PlotZ(app, z);
            hold(app.UIAxes, 'off');
            pause(2);
     
	    Z = z*app.ValueOutZ0.Value;
	    ZL = -imag(Z);
	    L = ZL/(2*pi*f);
        end
        
        function L_Serie(app)
            f = app.Freq1.Value;
            L = getL_Serie(app, f);
            ZL = complex(0, 2*pi*f*L);
            zL = ZL/app.ValueOutZ0.Value;
            
            hold(app.UIAxes, 'on');
            gamazL = PlotZ(app, zL);
            hold(app.UIAxes, 'off');
            
            Zl = complex(app.ValueRZL.Value, app.ValueIZL.Value);
            zl = Zl/app.ValueOutZ0.Value;
            z = Go2R1(app, zl, 2);
            
            d = getD(app, zl, z);
            app.DLSerie.Value = d;
            app.LSerie.Value = L;
        end

        function L = getL_Paralelo(app, f)
            Zl = complex(app.ValueRZL.Value, app.ValueIZL.Value);
       	    zl = Zl/app.ValueOutZ0.Value;
            yl = 1/zl;
            
            hold(app.UIAxes, 'on');
            gamayl = PlotZ(app, yl);
            hold(app.UIAxes, 'off');
            pause(2);
           	
            y = Go2R1(app, yl, 1);
            hold(app.UIAxes, 'on');
            gamay = PlotZ(app, y);
            hold(app.UIAxes, 'off');
            pause(2);
            
            Y = y/app.ValueOutZ0.Value;
            YL = imag(Y);
            L = 1/(2*pi*f*YL);
        end
        
        function L_Paralelo(app)
            f = app.Freq1.Value;
            L = getL_Paralelo(app, f);
            YL = complex(0, -1/(2*pi*f*L));
            yL = YL*app.ValueOutZ0.Value;
            
            hold(app.UIAxes, 'on');
            gamaYL = PlotZ(app, yL);
            hold(app.UIAxes, 'off');
           	
            Zl = complex(app.ValueRZL.Value, app.ValueIZL.Value);
            zl = Zl/app.ValueOutZ0.Value;
            yl = 1/zl;
            
            y = Go2R1(app, yl, 1);
            
            d = getD(app, yl, y);
            app.DLParalelo.Value = d;
            app.LParalelo.Value = L;
            
        end

        function Quarto(app)
            Zl = complex(app.ValueRZL.Value, app.ValueIZL.Value);
            Z0 = app.ValueOutZ0.Value;
            Zm = sqrt(Z0*Zl);
            app.ValueZm.Value = Zm;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Panel1.Visible = 'on';
            app.PanelAxes.Visible = 'off';
            app.MainPanel.Visible = 'off';
            app.Warning.Visible = 'off';
            app.Panel2.Visible = 'off';
            app.Panel3.Visible = 'off';
            app.PanelCSerie.Visible = 'off';
            app.PanelCParalelo.Visible = 'off';
            app.PanelLSerie.Visible = 'off';
            app.PanelLParalelo.Visible = 'off';
            app.ButtonClearAxes.Visible = 'off';
            app.PanelQuarto.Visible = 'off';
            app.PanelTocoSimples.Visible = 'off';
            axis(app.UIAxes, 'equal');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
        end

        % SliderR value changing function
        function updateR(app, event)
            app.ValueR.Value = event.Value;
        end

        % ValueR value changed function
        function updateSliderR(app)
            app.SliderR.Value = app.ValueR.Value;
        end

        % SliderL value changing function
        function updateL(app, event)
            app.ValueL.Value = event.Value;
        end

        % ValueL value changed function
        function updateSliderL(app)
            app.SliderL.Value = app.ValueL.Value;
        end

        % SliderC value changing function
        function updateC(app, event)
            app.ValueC.Value = event.Value;
        end

        % SliderG value changing function
        function updateG(app, event)
            app.ValueG.Value = event.Value;
        end

        % ValueC value changed function
        function updateSliderC(app)
            app.SliderC.Value = app.ValueC.Value;
        end

        % ValueG value changed function
        function updateSliderG(app)
            app.SliderG.Value = app.ValueG.Value;
        end

        % ButtonChange2Z0 button pushed function
        function ChangeToZ0(app)
            app.Panel1.Visible = 'off';
            app.Panel2.Visible = 'on';
        end

        % ButtonPanel2OK button pushed function
        function Panel2OK(app)
            app.Panel2.Visible = 'off';
            app.MainPanel.Visible = 'on';
            app.PanelAxes.Visible = 'on';
            app.UIAxes.Visible = 'on';
            app.ButtonClearAxes.Visible = 'on';
                                              
            InitAxes(app);
            
            app.ValueOutZ0.Value = app.ValueInZ0.Value;
            app.ValueOutBeta.Value = app.ValueInBeta.Value;
            app.Freq1.Value = app.Freq2.Value;
        end

        % ButtonCalcZ0 button pushed function
        function CalcZ0(app)
            app.Panel1.Visible = 'off';
            
            R = app.ValueR.Value*10^(app.PotR.Value);
            L = app.ValueL.Value*10^(app.PotL.Value);
            C = app.ValueC.Value*10^(app.PotC.Value);
            G = app.ValueG.Value*10^(app.PotG.Value);
            f = app.Freq1.Value;
            app.Freq2.Value = app.Freq1.Value;
            
            Z0 = sqrt((R+j*2*pi*L)/(G+j*2*pi*C));
            gama = sqrt((R+j*2*pi*L)*(G+j*2*pi*C));
            
            if((imag(Z0) == 0) && (real(gama) == 0))
                app.Panel3.Visible = 'on';
                app.ValueOutZ0.Value = real(Z0);
                app.ValueOutBeta.Value = imag(gama);
            else
                app.Warning.Visible = 'on';
                app.ValueRwZ0.Value = real(Z0);
                app.ValueIwZ0.Value = imag(Z0);
                app.ValueRwGama.Value = real(gama);
                app.ValueIwGama.Value = imag(gama);
            end
        end

        % ButtonWarningOK button pushed function
        function WarningOK(app)
            app.Panel1.Visible = 'on';
            app.Warning.Visible = 'off';
        end

        % ButtonPanel3OK button pushed function
        function Panel3OK(app)
            app.Panel3.Visible = 'off';
            app.MainPanel.Visible = 'on';
            app.PanelAxes.Visible = 'on';
            app.UIAxes.Visible = 'on';
            app.ButtonClearAxes.Visible = 'on';
            
            InitAxes(app);
        end

        % ButtonRestart button pushed function
        function Restart(app)
            startupFcn(app);
            ClearFields(app);
        end

        % ButtonPlotZL button pushed function
        function PlotZL(app)
            zl = (app.ValueRZL.Value + j*app.ValueIZL.Value)/app.ValueOutZ0.Value;
            
            hold(app.UIAxes, 'on');
            
            gama = PlotZ(app, zl);
            
            GamaConstCircle(app, real(gama), imag(gama));
            app.ValueRGamaL.Value = real(gama);
            app.ValueIGamaL.Value = imag(gama);

            hold(app.UIAxes, 'off');
        end

        % ButtonClearAxes button pushed function
        function ClearAxes(app)
            cla(app.UIAxes);
            InitAxes(app);
        end

        % ButtonCalcZin button pushed function
        function CalcZin(app)
            hold(app.UIAxes, 'on');
            gamaL = complex(app.ValueRGamaL.Value, app.ValueIGamaL.Value);
            
            teta = gama2teta(app, gamaL);
            
            d_linha = teta2d(app, teta) + app.ValueD.Value;
            teta_linha = d2teta(app, d_linha);
            gamaInR = abs(gamaL)*cos(teta_linha);
            gamaInI = abs(gamaL)*sin(teta_linha);
            app.ValueRGamaIn.Value = gamaInR;
            app.ValueIGamaIn.Value = gamaInI;
            
            gamain = complex(gamaInR, gamaInI);
            
            zin = (gamain+1)/(1-gamain);
            gama = PlotZ(app, zin);
            app.ValueRZin.Value = real(zin)*app.ValueOutZ0.Value;
            app.ValueIZin.Value = imag(zin)*app.ValueOutZ0.Value;
            hold(app.UIAxes, 'off');
        end

        % ButtonCalcMatching button pushed function
        function CalcMatching(app)
            if app.MatchingType.Value == '0'
                app.MainPanel.Visible = 'off';
                L_Serie(app);
                app.PanelLSerie.Visible = 'on';
            elseif app.MatchingType.Value == '1'
                app.MainPanel.Visible = 'off';
                L_Paralelo(app);
                app.PanelLParalelo.Visible = 'on';
            elseif app.MatchingType.Value == '2'
                app.MainPanel.Visible = 'off';
                C_Serie(app);
                app.PanelCSerie.Visible = 'on';
            elseif app.MatchingType.Value == '3'
                app.MainPanel.Visible = 'off';
                C_Paralelo(app);
                app.PanelCParalelo.Visible = 'on';
            elseif app.MatchingType.Value == '4'
                app.MainPanel.Visible = 'off';
                Quarto(app);
                app.PanelQuarto.Visible = 'on';
            elseif app.MatchingType.Value == '5'
                app.MainPanel.Visible = 'off';
                app.PanelTocoSimples.Visible = 'on';
            end
        end

        % ButtonCSerie button pushed function
        function OKCSerie(app)
            app.MainPanel.Visible = 'on';
            app.PanelCSerie.Visible = 'off';
        end

        % ButtonCParalelo button pushed function
        function OKCParalelo(app)
            app.MainPanel.Visible = 'on';
            app.PanelCParalelo.Visible = 'off';
        end

        % ButtonLSerie button pushed function
        function OKLSerie(app)
            app.MainPanel.Visible = 'on';
            app.PanelLSerie.Visible = 'off';
        end

        % ButtonLParalelo button pushed function
        function OKLParalelo(app)
            app.MainPanel.Visible = 'on';
            app.PanelLParalelo.Visible = 'off';
        end

        % ButtonOKZm button pushed function
        function OKZm(app)
            app.MainPanel.Visible = 'on';
            app.PanelZm.Visible = 'off';
        end

        % ButtonCloseToco button pushed function
        function CloseToco(app)
            app.PanelTocoSimples.Visible = 'off';
            app.MainPanel.Visible = 'on';
        end

        % ButtonOKToco button pushed function
        function CalcToco(app)
            hold(app.UIAxes, 'on');
            if app.ButtonSerie.Value == 1
               ZL = complex(app.ValueRZL.Value, app.ValueIZL.Value);
               a = ZL/app.ValueOutZ0.Value;
               
               if app.ButtonCC.Value == 1
                   b = 0;
                   c = Go2R1(app, a, 2);
                   gamaC = PlotZ(app, c);
                   pause(2);
                   Point(app, -1, 0);
                   pause(2);
                   d = complex(0, -imag(c));
                   gamaD = PlotZ(app, d);
                   app.ValueT.Value = getDfrom0(app, d);
               elseif app.ButtonCA.Value == 1
                   b = inf;
                   c = Go2R1(app, a, 1);
                   gamaC = PlotZ(app, c);
                   pause(2);
                   Point(app, 1, 0);
                   pause(2);
                   d = complex(0, -imag(c));
                   gamaD = PlotZ(app, d);
                   app.ValueT.Value = getDfromInf(app, d);
               end
            elseif app.ButtonParalelo.Value == 1
               ZL = complex(app.ValueRZL.Value, app.ValueIZL.Value);
               zl = ZL/app.ValueOutZ0.Value;
               a = 1/zl;
               gamayl = PlotZ(app, a);
               pause(2);
               
               if app.ButtonCC.Value == 1
                   b = inf;
                   c = Go2R1(app, a, 1);
                   gamaC = PlotZ(app, c);
                   pause(2);
                   Point(app, 1, 0);
                   pause(2);
                   d = complex(0, -imag(c));
                   gamaD = PlotZ(app, d);
                   app.ValueT.Value = getDfromInf(app, d);
               elseif app.ButtonCA.Value == 1
                   b = 0;
                   c = Go2R1(app, a, 2);
                   gamaC = PlotZ(app, c);
                   pause(2);
                   Point(app, -1, 0);
                   pause(2);
                   d = complex(0, -imag(c));
                   gamaD = PlotZ(app, d);
                   app.ValueT.Value = getDfrom0(app, d);
               end
            end
            
            app.ValueDT.Value = getD(app, a, c);
            hold(app.UIAxes, 'off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel1 = uipanel(app.UIFigure);
            app.Panel1.Title = 'Parâmetros Distribuídos';
            app.Panel1.Units = 'pixels';
            app.LabelSlider = uilabel(app.Panel1);
            app.LabelSlider.Text = 'R:';
            app.SliderR = uislider(app.Panel1);
            app.SliderR.Limits = [0 10];
            app.SliderR.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SliderR.ValueChangingFcn = createCallbackFcn(app, @updateR, true);
            app.PanelR = uipanel(app.Panel1);
            app.PanelR.Units = 'pixels';
            app.ValueR = uieditfield(app.PanelR, 'numeric');
            app.ValueR.ValueChangedFcn = createCallbackFcn(app, @updateSliderR);
            app.ValueR.Limits = [0 10];
            app.ValueR.ValueDisplayFormat = '%.3f';
            app.Label = uilabel(app.PanelR);
            app.Label.Text = '10^';
            app.PotR = uieditfield(app.PanelR, 'numeric');
            app.PotR.ValueDisplayFormat = '%.0f';
            app.Label2 = uilabel(app.PanelR);
            app.Label2.Text = 'Ω';
            app.Label3 = uilabel(app.Panel1);
            app.Label3.Text = 'L:';
            app.SliderL = uislider(app.Panel1);
            app.SliderL.Limits = [0 10];
            app.SliderL.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SliderL.ValueChangingFcn = createCallbackFcn(app, @updateL, true);
            app.PanelL = uipanel(app.Panel1);
            app.PanelL.Units = 'pixels';
            app.ValueL = uieditfield(app.PanelL, 'numeric');
            app.ValueL.ValueChangedFcn = createCallbackFcn(app, @updateSliderL);
            app.ValueL.Limits = [0 10];
            app.ValueL.ValueDisplayFormat = '%.3f';
            app.Label4 = uilabel(app.PanelL);
            app.Label4.Text = '10^';
            app.PotL = uieditfield(app.PanelL, 'numeric');
            app.PotL.ValueDisplayFormat = '%.0f';
            app.Label5 = uilabel(app.PanelL);
            app.Label5.Text = 'H';
            app.Label6 = uilabel(app.Panel1);
            app.Label6.Text = 'C:';
            app.SliderC = uislider(app.Panel1);
            app.SliderC.Limits = [0 10];
            app.SliderC.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SliderC.ValueChangingFcn = createCallbackFcn(app, @updateC, true);
            app.PanelC = uipanel(app.Panel1);
            app.PanelC.Units = 'pixels';
            app.ValueC = uieditfield(app.PanelC, 'numeric');
            app.ValueC.ValueChangedFcn = createCallbackFcn(app, @updateSliderC);
            app.ValueC.Limits = [0 10];
            app.ValueC.ValueDisplayFormat = '%.3f';
            app.Label7 = uilabel(app.PanelC);
            app.Label7.Text = '10^';
            app.PotC = uieditfield(app.PanelC, 'numeric');
            app.PotC.ValueDisplayFormat = '%.0f';
            app.Label8 = uilabel(app.PanelC);
            app.Label8.Text = 'F';
            app.Label9 = uilabel(app.Panel1);
            app.Label9.Text = 'G:';
            app.SliderG = uislider(app.Panel1);
            app.SliderG.Limits = [0 10];
            app.SliderG.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SliderG.ValueChangingFcn = createCallbackFcn(app, @updateG, true);
            app.PanelG = uipanel(app.Panel1);
            app.PanelG.Units = 'pixels';
            app.ValueG = uieditfield(app.PanelG, 'numeric');
            app.ValueG.ValueChangedFcn = createCallbackFcn(app, @updateSliderG);
            app.ValueG.Limits = [0 10];
            app.ValueG.ValueDisplayFormat = '%.3f';
            app.Label10 = uilabel(app.PanelG);
            app.Label10.Text = '10^';
            app.PotG = uieditfield(app.PanelG, 'numeric');
            app.PotG.ValueDisplayFormat = '%.0f';
            app.Label11 = uilabel(app.PanelG);
            app.Label11.Text = 'S';
            app.ButtonCalcZ0 = uibutton(app.Panel1, 'push');
            app.ButtonCalcZ0.ButtonPushedFcn = createCallbackFcn(app, @CalcZ0);
            app.ButtonCalcZ0.Text = 'Calcular Z0 e γ';
            app.ButtonChange2Z0 = uibutton(app.Panel1, 'push');
            app.ButtonChange2Z0.ButtonPushedFcn = createCallbackFcn(app, @ChangeToZ0);
            app.ButtonChange2Z0.Text = 'Entrar com Z0 e γ';
            app.Label20 = uilabel(app.Panel1);
            app.Label20.Text = 'Freq. de Operação (f):';
            app.Freq1 = uieditfield(app.Panel1, 'numeric');
            app.Freq1.Limits = [0 Inf];
            app.Freq1.ValueDisplayFormat = '%e';
            app.Label21 = uilabel(app.Panel1);
            app.Label21.Text = 'Hz';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Units = 'pixels';
            app.PanelZin = uipanel(app.MainPanel);
            app.PanelZin.Title = 'Imped. de Entrada (Zin)';
            app.PanelZin.Units = 'pixels';
            app.LabelNumericEditField13 = uilabel(app.PanelZin);
            app.LabelNumericEditField13.Text = 'Distância da Carga:';
            app.ValueD = uieditfield(app.PanelZin, 'numeric');
            app.ValueD.Limits = [0 Inf];
            app.Label15 = uilabel(app.PanelZin);
            app.Label15.Text = 'λ';
            app.ButtonCalcZin = uibutton(app.PanelZin, 'push');
            app.ButtonCalcZin.ButtonPushedFcn = createCallbackFcn(app, @CalcZin);
            app.ButtonCalcZin.Text = 'Calcular Zin';
            app.Label16 = uilabel(app.PanelZin);
            app.Label16.Text = 'Zin:';
            app.ValueRZin = uieditfield(app.PanelZin, 'numeric');
            app.ValueRZin.Limits = [0 Inf];
            app.ValueRZin.ValueDisplayFormat = '%.3f';
            app.ValueRZin.Editable = 'off';
            app.Label17 = uilabel(app.PanelZin);
            app.Label17.Text = '+ j';
            app.ValueIZin = uieditfield(app.PanelZin, 'numeric');
            app.ValueIZin.ValueDisplayFormat = '%.3f';
            app.ValueIZin.Editable = 'off';
            app.Label25 = uilabel(app.PanelZin);
            app.Label25.Text = 'Ω';
            app.Label40 = uilabel(app.PanelZin);
            app.Label40.Text = 'Γin:';
            app.ValueRGamaIn = uieditfield(app.PanelZin, 'numeric');
            app.ValueRGamaIn.Limits = [-1 1];
            app.ValueRGamaIn.ValueDisplayFormat = '%.3f';
            app.ValueRGamaIn.Editable = 'off';
            app.Label41 = uilabel(app.PanelZin);
            app.Label41.Text = '+ j';
            app.ValueIGamaIn = uieditfield(app.PanelZin, 'numeric');
            app.ValueIGamaIn.Limits = [-1 1];
            app.ValueIGamaIn.ValueDisplayFormat = '%.3f';
            app.ValueIGamaIn.Editable = 'off';
            app.Label42 = uilabel(app.PanelZin);
            app.Label42.Text = 'Ω';
            app.Panel10 = uipanel(app.MainPanel);
            app.Panel10.Title = 'Casamento de Impedância';
            app.Panel10.Units = 'pixels';
            app.MatchingType = uidropdown(app.Panel10);
            app.MatchingType.Items = {'Elemento Reativo - L (Série)', 'Elemento Reativo - L (Paralelo)', 'Elemento Reativo - C (Série)', 'Elemento Reativo - C (Paralelo)', 'Transformador de1/4 de Onda', 'Toco Simples'};
            app.MatchingType.ItemsData = {'0', '1', '2', '3', '4', '5'};
            app.MatchingType.Value = '0';
            app.ButtonCalcMatching = uibutton(app.Panel10, 'push');
            app.ButtonCalcMatching.ButtonPushedFcn = createCallbackFcn(app, @CalcMatching);
            app.ButtonCalcMatching.Text = 'Realizar';
            app.PanelZL = uipanel(app.MainPanel);
            app.PanelZL.Title = 'Carga na Linha de Transmissão';
            app.PanelZL.Units = 'pixels';
            app.LabelNumericEditField9 = uilabel(app.PanelZL);
            app.LabelNumericEditField9.Text = 'Z.L:';
            app.ValueRZL = uieditfield(app.PanelZL, 'numeric');
            app.ValueRZL.Limits = [0 Inf];
            app.ValueRZL.ValueDisplayFormat = '%.3f';
            app.LabelNumericEditField10 = uilabel(app.PanelZL);
            app.LabelNumericEditField10.Text = '+ j';
            app.ValueIZL = uieditfield(app.PanelZL, 'numeric');
            app.ValueIZL.ValueDisplayFormat = '%.3f';
            app.Label24 = uilabel(app.PanelZL);
            app.Label24.Text = 'Ω';
            app.ButtonPlotZL = uibutton(app.PanelZL, 'push');
            app.ButtonPlotZL.ButtonPushedFcn = createCallbackFcn(app, @PlotZL);
            app.ButtonPlotZL.Text = 'Plotar Z.L';
            app.Label37 = uilabel(app.PanelZL);
            app.Label37.Text = 'ΓL:';
            app.ValueRGamaL = uieditfield(app.PanelZL, 'numeric');
            app.ValueRGamaL.Limits = [-1 1];
            app.ValueRGamaL.ValueDisplayFormat = '%.3f';
            app.ValueRGamaL.Editable = 'off';
            app.Label38 = uilabel(app.PanelZL);
            app.Label38.Text = '+ j';
            app.ValueIGamaL = uieditfield(app.PanelZL, 'numeric');
            app.ValueIGamaL.Limits = [-1 1];
            app.ValueIGamaL.ValueDisplayFormat = '%.3f';
            app.ValueIGamaL.Editable = 'off';
            app.Label39 = uilabel(app.PanelZL);
            app.Label39.Text = 'Ω';
            app.Warning = uipanel(app.UIFigure);
            app.Warning.Title = 'Aviso!!';
            app.Warning.Units = 'pixels';
            app.Label26 = uilabel(app.Warning);
            app.Label26.Text = 'Imped. Característica (Z0):';
            app.ValueRwZ0 = uieditfield(app.Warning, 'numeric');
            app.ValueRwZ0.Limits = [0 Inf];
            app.ValueRwZ0.ValueDisplayFormat = '%.3f';
            app.ValueRwZ0.Editable = 'off';
            app.Label27 = uilabel(app.Warning);
            app.Label27.Text = '+ j';
            app.ValueIwZ0 = uieditfield(app.Warning, 'numeric');
            app.ValueIwZ0.ValueDisplayFormat = '%.3f';
            app.ValueIwZ0.Editable = 'off';
            app.Label28 = uilabel(app.Warning);
            app.Label28.Text = 'Ω';
            app.Label29 = uilabel(app.Warning);
            app.Label29.Text = 'Const. de Propagação (γ):';
            app.ValueRwGama = uieditfield(app.Warning, 'numeric');
            app.ValueRwGama.Limits = [0 Inf];
            app.ValueRwGama.ValueDisplayFormat = '%.3f';
            app.ValueRwGama.Editable = 'off';
            app.Label30 = uilabel(app.Warning);
            app.Label30.Text = '+ j';
            app.ValueIwGama = uieditfield(app.Warning, 'numeric');
            app.ValueIwGama.ValueDisplayFormat = '%.3f';
            app.ValueIwGama.Editable = 'off';
            app.Label31 = uilabel(app.Warning);
            app.Label31.Text = 'Ω';
            app.Panel13 = uipanel(app.Warning);
            app.Panel13.Title = 'Observação';
            app.Panel13.Units = 'pixels';
            app.TextArea2 = uitextarea(app.Panel13);
            app.TextArea2.Editable = 'off';
            app.TextArea2.Value = {'A Carta de Smith só pode ser utilizada para Linhas sem Perda, isto é, Imped. Característica real e Const. de Propagação puramente imaginária. Por este motivo, com estes parâmetros, não é possível utilizá-la.'};
            app.ButtonWarningOK = uibutton(app.Warning, 'push');
            app.ButtonWarningOK.ButtonPushedFcn = createCallbackFcn(app, @WarningOK);
            app.ButtonWarningOK.Text = 'OK, Ciente';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Entrar com Z0 e γ';
            app.Panel2.Units = 'pixels';
            app.LabelNumericEditField11 = uilabel(app.Panel2);
            app.LabelNumericEditField11.Text = 'Imped. Característica (Z0):';
            app.ValueInZ0 = uieditfield(app.Panel2, 'numeric');
            app.Label13 = uilabel(app.Panel2);
            app.Label13.Text = 'Ω';
            app.LabelNumericEditField12 = uilabel(app.Panel2);
            app.LabelNumericEditField12.Text = '+ j';
            app.ValueInBeta = uieditfield(app.Panel2, 'numeric');
            app.Label12 = uilabel(app.Panel2);
            app.Label12.Text = 'Const. de Propagação (γ):';
            app.Label14 = uilabel(app.Panel2);
            app.Label14.Text = '/m';
            app.Panel8 = uipanel(app.Panel2);
            app.Panel8.Title = 'Observação';
            app.Panel8.Units = 'pixels';
            app.TextArea = uitextarea(app.Panel8);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Lembre-se: Para utilização da Carta de Smith, a Imped. Característica deve ser real e a Const. de Propagação deve ser puramente imaginária.'};
            app.ButtonPanel2OK = uibutton(app.Panel2, 'push');
            app.ButtonPanel2OK.ButtonPushedFcn = createCallbackFcn(app, @Panel2OK);
            app.ButtonPanel2OK.Text = 'OK';
            app.Label43 = uilabel(app.Panel2);
            app.Label43.Text = 'Freq. de Operação (f):';
            app.Freq2 = uieditfield(app.Panel2, 'numeric');
            app.Freq2.Limits = [0 Inf];
            app.Freq2.ValueDisplayFormat = '%e';
            app.Label44 = uilabel(app.Panel2);
            app.Label44.Text = 'Hz';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Resultado de Z0 e γ';
            app.Panel3.Units = 'pixels';
            app.Label32 = uilabel(app.Panel3);
            app.Label32.Text = 'Imped. Característica (Z0):';
            app.ValueOutZ0 = uieditfield(app.Panel3, 'numeric');
            app.ValueOutZ0.Editable = 'off';
            app.Label33 = uilabel(app.Panel3);
            app.Label33.Text = 'Ω';
            app.Label34 = uilabel(app.Panel3);
            app.Label34.Text = '+ j';
            app.ValueOutBeta = uieditfield(app.Panel3, 'numeric');
            app.ValueOutBeta.Editable = 'off';
            app.Label35 = uilabel(app.Panel3);
            app.Label35.Text = 'Const. de Propagação (γ):';
            app.Label36 = uilabel(app.Panel3);
            app.Label36.Text = '/m';
            app.ButtonPanel3OK = uibutton(app.Panel3, 'push');
            app.ButtonPanel3OK.ButtonPushedFcn = createCallbackFcn(app, @Panel3OK);
            app.ButtonPanel3OK.Text = 'OK';
            app.ButtonRestart = uibutton(app.UIFigure, 'push');
            app.ButtonRestart.ButtonPushedFcn = createCallbackFcn(app, @Restart);
            app.ButtonRestart.Icon = 'RestartIcon.png';
            app.ButtonRestart.Text = 'Reiniciar';
            app.PanelAxes = uipanel(app.UIFigure);
            app.PanelAxes.Units = 'pixels';
            app.UIAxes = uiaxes(app.PanelAxes);
            title(app.UIAxes, 'Smith Chart');
            app.ButtonClearAxes = uibutton(app.UIFigure, 'push');
            app.ButtonClearAxes.ButtonPushedFcn = createCallbackFcn(app, @ClearAxes);
            app.ButtonClearAxes.Text = 'Limpar Carta';
            app.PanelCSerie = uipanel(app.UIFigure);
            app.PanelCSerie.Title = 'C_Série';
            app.PanelCSerie.Units = 'pixels';
            app.Label45 = uilabel(app.PanelCSerie);
            app.Label45.Text = 'Dist. da Carga:';
            app.DCSerie = uieditfield(app.PanelCSerie, 'numeric');
            app.DCSerie.Limits = [0 Inf];
            app.DCSerie.Editable = 'off';
            app.Label46 = uilabel(app.PanelCSerie);
            app.Label46.Text = 'λ';
            app.Label47 = uilabel(app.PanelCSerie);
            app.Label47.Text = 'Capacitor:';
            app.CSerie = uieditfield(app.PanelCSerie, 'numeric');
            app.CSerie.Limits = [0 Inf];
            app.CSerie.ValueDisplayFormat = '%e';
            app.CSerie.Editable = 'off';
            app.Label48 = uilabel(app.PanelCSerie);
            app.Label48.Text = 'F';
            app.ButtonCSerie = uibutton(app.PanelCSerie, 'push');
            app.ButtonCSerie.ButtonPushedFcn = createCallbackFcn(app, @OKCSerie);
            app.ButtonCSerie.Text = 'OK';
            app.PanelCParalelo = uipanel(app.UIFigure);
            app.PanelCParalelo.Title = 'C_Paralelo';
            app.PanelCParalelo.Units = 'pixels';
            app.Label49 = uilabel(app.PanelCParalelo);
            app.Label49.Text = 'Dist. da Carga:';
            app.DCParalelo = uieditfield(app.PanelCParalelo, 'numeric');
            app.DCParalelo.Limits = [0 Inf];
            app.DCParalelo.Editable = 'off';
            app.Label50 = uilabel(app.PanelCParalelo);
            app.Label50.Text = 'λ';
            app.Label51 = uilabel(app.PanelCParalelo);
            app.Label51.Text = 'Capacitor:';
            app.CParalelo = uieditfield(app.PanelCParalelo, 'numeric');
            app.CParalelo.Limits = [0 Inf];
            app.CParalelo.ValueDisplayFormat = '%e';
            app.CParalelo.Editable = 'off';
            app.Label52 = uilabel(app.PanelCParalelo);
            app.Label52.Text = 'F';
            app.ButtonCParalelo = uibutton(app.PanelCParalelo, 'push');
            app.ButtonCParalelo.ButtonPushedFcn = createCallbackFcn(app, @OKCParalelo);
            app.ButtonCParalelo.Text = 'OK';
            app.PanelLParalelo = uipanel(app.UIFigure);
            app.PanelLParalelo.Title = 'L_Paralelo';
            app.PanelLParalelo.Units = 'pixels';
            app.Label53 = uilabel(app.PanelLParalelo);
            app.Label53.Text = 'Dist. da Carga:';
            app.DLParalelo = uieditfield(app.PanelLParalelo, 'numeric');
            app.DLParalelo.Limits = [0 Inf];
            app.DLParalelo.Editable = 'off';
            app.Label54 = uilabel(app.PanelLParalelo);
            app.Label54.Text = 'λ';
            app.Label55 = uilabel(app.PanelLParalelo);
            app.Label55.Text = 'Indutor:';
            app.LParalelo = uieditfield(app.PanelLParalelo, 'numeric');
            app.LParalelo.Limits = [0 Inf];
            app.LParalelo.ValueDisplayFormat = '%e';
            app.LParalelo.Editable = 'off';
            app.Label56 = uilabel(app.PanelLParalelo);
            app.Label56.Text = 'H';
            app.ButtonLParalelo = uibutton(app.PanelLParalelo, 'push');
            app.ButtonLParalelo.ButtonPushedFcn = createCallbackFcn(app, @OKLParalelo);
            app.ButtonLParalelo.Text = 'OK';
            app.PanelLSerie = uipanel(app.UIFigure);
            app.PanelLSerie.Title = 'L_Série';
            app.PanelLSerie.Units = 'pixels';
            app.Label57 = uilabel(app.PanelLSerie);
            app.Label57.Text = 'Dist. da Carga:';
            app.DLSerie = uieditfield(app.PanelLSerie, 'numeric');
            app.DLSerie.Limits = [0 Inf];
            app.DLSerie.Editable = 'off';
            app.Label58 = uilabel(app.PanelLSerie);
            app.Label58.Text = 'λ';
            app.Label59 = uilabel(app.PanelLSerie);
            app.Label59.Text = 'Indutor:';
            app.LSerie = uieditfield(app.PanelLSerie, 'numeric');
            app.LSerie.Limits = [0 Inf];
            app.LSerie.ValueDisplayFormat = '%e';
            app.LSerie.Editable = 'off';
            app.Label60 = uilabel(app.PanelLSerie);
            app.Label60.Text = 'H';
            app.ButtonLSerie = uibutton(app.PanelLSerie, 'push');
            app.ButtonLSerie.ButtonPushedFcn = createCallbackFcn(app, @OKLSerie);
            app.ButtonLSerie.Text = 'OK';
            app.PanelQuarto = uipanel(app.UIFigure);
            app.PanelQuarto.Title = 'Transformador de λ/4';
            app.PanelQuarto.Units = 'pixels';
            app.Label61 = uilabel(app.PanelQuarto);
            app.Label61.Text = 'Imp. do Transform.:';
            app.ValueZm = uieditfield(app.PanelQuarto, 'numeric');
            app.ValueZm.Limits = [0 Inf];
            app.ValueZm.ValueDisplayFormat = '%.3f';
            app.ValueZm.Editable = 'off';
            app.Label62 = uilabel(app.PanelQuarto);
            app.Label62.Text = 'Ω';
            app.ButtonOKZm = uibutton(app.PanelQuarto, 'push');
            app.ButtonOKZm.ButtonPushedFcn = createCallbackFcn(app, @OKZm);
            app.ButtonOKZm.Text = 'OK';
            app.PanelTocoSimples = uipanel(app.UIFigure);
            app.PanelTocoSimples.Title = 'Toco Simples';
            app.PanelTocoSimples.Units = 'pixels';
            app.ButtonGroup = uibuttongroup(app.PanelTocoSimples);
            app.ButtonGroup.Title = 'Tipo';
            app.ButtonGroup.Units = 'pixels';
            app.ButtonSerie = uiradiobutton(app.ButtonGroup);
            app.ButtonSerie.Text = 'Série';
            app.ButtonSerie.Value = true;
            app.ButtonParalelo = uiradiobutton(app.ButtonGroup);
            app.ButtonParalelo.Text = 'Paralelo';
            app.ButtonGroup2 = uibuttongroup(app.PanelTocoSimples);
            app.ButtonGroup2.Title = 'Fechamento';
            app.ButtonGroup2.Units = 'pixels';
            app.ButtonCC = uiradiobutton(app.ButtonGroup2);
            app.ButtonCC.Text = 'Curto Circuito';
            app.ButtonCC.Value = true;
            app.ButtonCA = uiradiobutton(app.ButtonGroup2);
            app.ButtonCA.Text = 'Circuito Aberto';
            app.Label63 = uilabel(app.PanelTocoSimples);
            app.Label63.Text = 'Distância da Carga:';
            app.ValueDT = uieditfield(app.PanelTocoSimples, 'numeric');
            app.ValueDT.Limits = [0 Inf];
            app.ValueDT.Editable = 'off';
            app.Label64 = uilabel(app.PanelTocoSimples);
            app.Label64.Text = 'λ';
            app.Label65 = uilabel(app.PanelTocoSimples);
            app.Label65.Text = 'Tamanho do Toco:';
            app.ValueT = uieditfield(app.PanelTocoSimples, 'numeric');
            app.ValueT.Limits = [0 Inf];
            app.ValueT.Editable = 'off';
            app.Label66 = uilabel(app.PanelTocoSimples);
            app.Label66.Text = 'λ';
            app.ButtonCloseToco = uibutton(app.PanelTocoSimples, 'push');
            app.ButtonCloseToco.ButtonPushedFcn = createCallbackFcn(app, @CloseToco);
            app.ButtonCloseToco.Text = 'Fechar';
            app.ButtonOKToco = uibutton(app.PanelTocoSimples, 'push');
            app.ButtonOKToco.ButtonPushedFcn = createCallbackFcn(app, @CalcToco);
            app.ButtonOKToco.Text = 'OK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SmithChart()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end